classdef gridline < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        PredictButton             matlab.ui.control.Button
        VCEditField               matlab.ui.control.NumericEditField
        VCEditFieldLabel          matlab.ui.control.Label
        VbEditField               matlab.ui.control.NumericEditField
        VbEditFieldLabel          matlab.ui.control.Label
        VaEditField               matlab.ui.control.NumericEditField
        VaEditFieldLabel          matlab.ui.control.Label
        IcEditField               matlab.ui.control.NumericEditField
        IcEditFieldLabel          matlab.ui.control.Label
        IbEditField               matlab.ui.control.NumericEditField
        IbEditFieldLabel          matlab.ui.control.Label
        IaEditField               matlab.ui.control.NumericEditField
        IaEditFieldLabel          matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        FaultdetectionandclassificatLabel_2  matlab.ui.control.Label
        FaultdetectionandclassificatLabel  matlab.ui.control.Label
        FaultLamp                 matlab.ui.control.Lamp
        FaultLampLabel            matlab.ui.control.Label
        NofaultLamp               matlab.ui.control.Lamp
        NofaultLampLabel          matlab.ui.control.Label
        FaultclassEditField       matlab.ui.control.EditField
        FaultclassEditFieldLabel  matlab.ui.control.Label
        FaulttypeEditField        matlab.ui.control.EditField
        FaulttypeEditFieldLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            load("classModel.mat")
            Ia= app.IaEditField.Value;
            Ib= app.IbEditField.Value;
            Ic= app.IcEditField.Value;
            Va= app.VaEditField.Value;
            Vb= app.VbEditField.Value;
            Vc= app.VCEditField.Value;
            G_C_B_A= 0;
            pred=table(Ia, Ib, Ic, Va, Vb, Vc, G_C_B_A);
            yfit = classModel.predictFcn(pred);
            yfit= char(yfit)
            % display predictions
            if yfit == '0 0 0 0';
                app.NofaultLamp.Color = [0 1 0];
                app.FaultLamp.Color = [1 1 1];
            else
                app.FaultLamp.Color = [1 0 0];
                app.NofaultLamp.Color = [1 1 1];

            end

            app.FaulttypeEditField.Value= yfit;
            if yfit == '1 1 1 1';
                app.FaultclassEditField.Value= 'LLLG fault( Three phase symmetrical fault)';
            elseif yfit == '0 0 0 0';
                app.FaultclassEditField.Value= 'No fault';
            elseif yfit == '1 0 0 1';
                app.FaultclassEditField.Value= 'LG fault (Between Phase A and Gnd)';
            elseif yfit == '0 0 1 1';
                app.FaultclassEditField.Value= 'LL fault (Between Phase A and Phase B)';
            elseif yfit == '0 1 1 1';
                app.FaultclassEditField.Value= 'LLL Fault(Between all three phases)';
            elseif yfit == '0 1 1 0';
                app.FaultclassEditField.Value= 'LL Fault(Between Phase Ground and Phase B)';
            elseif yfit == '1 0 0 0';
                app.FaultclassEditField.Value= 'LL Fault(Ground fault)';
            else 
                app.FaultclassEditField.Value= 'Unpredictable fault';
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {301, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IaEditFieldLabel = uilabel(app.LeftPanel);
            app.IaEditFieldLabel.Text = 'Ia';
            app.IaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IbEditFieldLabel = uilabel(app.LeftPanel);
            app.IbEditFieldLabel.Text = 'Ib';
            app.IbEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IcEditFieldLabel = uilabel(app.LeftPanel);
            app.IcEditFieldLabel.Text = 'Ic';
            app.IcEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VaEditFieldLabel = uilabel(app.LeftPanel);
            app.VaEditFieldLabel.Text = 'Va';
            app.VaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VbEditFieldLabel = uilabel(app.LeftPanel);
            app.VbEditFieldLabel.Text = 'Vb';
            app.VbEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VCEditFieldLabel = uilabel(app.LeftPanel);
            app.VCEditFieldLabel.Text = 'VC';
            app.VCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PredictButton = uibutton(app.LeftPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.RightPanel = uipanel(app.GridLayout);
            app.FaulttypeEditFieldLabel = uilabel(app.RightPanel);
            app.FaulttypeEditFieldLabel.Text = 'Fault type';
            app.FaulttypeEditField = uieditfield(app.RightPanel, 'text');
            app.FaultclassEditFieldLabel = uilabel(app.RightPanel);
            app.FaultclassEditFieldLabel.Text = 'Fault class';
            app.FaultclassEditField = uieditfield(app.RightPanel, 'text');
            app.NofaultLampLabel = uilabel(app.RightPanel);
            app.NofaultLampLabel.Text = 'No fault';
            app.NofaultLamp = uilamp(app.RightPanel);
            app.FaultLampLabel = uilabel(app.RightPanel);
            app.FaultLampLabel.Text = 'Fault';
            app.FaultLamp = uilamp(app.RightPanel);
            app.FaultdetectionandclassificatLabel = uilabel(app.RightPanel);
            app.FaultdetectionandclassificatLabel.Text = {'Electrical Fault detection and classification'; 'in distribution gridlines.'};
            app.FaultdetectionandclassificatLabel_2 = uilabel(app.RightPanel);
            app.FaultdetectionandclassificatLabel_2.Text = '-Gunjan Vaishnav';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gridline

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end